classdef IsoScope < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        FileMenu                 matlab.ui.container.Menu
        OpenMenu                 matlab.ui.container.Menu
        Menu2                    matlab.ui.container.Menu
        Toolbar                  matlab.ui.container.Toolbar
        PushToolOpen             matlab.ui.container.toolbar.PushTool
        PushTool2                matlab.ui.container.toolbar.PushTool
        PushTool3                matlab.ui.container.toolbar.PushTool
        PushTool4                matlab.ui.container.toolbar.PushTool
        PushTool5                matlab.ui.container.toolbar.PushTool
        PushTool6                matlab.ui.container.toolbar.PushTool
        GridLayout               matlab.ui.container.GridLayout
        GridLayout11             matlab.ui.container.GridLayout
        Button_cursor            matlab.ui.control.StateButton
        Button_scale             matlab.ui.control.StateButton
        viewButtonGroup          matlab.ui.container.ButtonGroup
        ButtonView4              matlab.ui.control.ToggleButton
        ButtonView3              matlab.ui.control.ToggleButton
        ButtonView2              matlab.ui.control.ToggleButton
        ButtonView1              matlab.ui.control.ToggleButton
        imgTabGroup              matlab.ui.container.TabGroup
        ImageTab                 matlab.ui.container.Tab
        GridLayoutImage          matlab.ui.container.GridLayout
        CursorPanel              matlab.ui.container.Panel
        CursorSigLabel           matlab.ui.control.Label
        CursorIDSpinner          matlab.ui.control.Spinner
        IDSpinnerLabel           matlab.ui.control.Label
        CursorYLabel             matlab.ui.control.Label
        CursorXLabel             matlab.ui.control.Label
        CursorColorButton        matlab.ui.control.Button
        CursorOnOffCheckBox      matlab.ui.control.CheckBox
        ScalebarPanel            matlab.ui.container.Panel
        ScaleBarOnOffCheckBox    matlab.ui.control.CheckBox
        ScaleBarUpdateButton     matlab.ui.control.Button
        ScaleBarColorButton      matlab.ui.control.Button
        UIAxesMain               matlab.ui.control.UIAxes
        IsoViewTab               matlab.ui.container.Tab
        WeightTab                matlab.ui.container.Tab
        SettingsTab              matlab.ui.container.Tab
        funTabGroup              matlab.ui.container.TabGroup
        MSTab                    matlab.ui.container.Tab
        GridLayout6              matlab.ui.container.GridLayout
        UIAxesMS                 matlab.ui.control.UIAxes
        UIAxesMS2                matlab.ui.control.UIAxes
        DintensityTab            matlab.ui.container.Tab
        GridLayout10             matlab.ui.container.GridLayout
        ListBox1                 matlab.ui.control.ListBox
        UIAxesSigHist            matlab.ui.control.UIAxes
        UIAxesSigDist            matlab.ui.control.UIAxes
        ErrdistTab               matlab.ui.container.Tab
        GridLayout7              matlab.ui.container.GridLayout
        ListBox2                 matlab.ui.control.ListBox
        UIAxesErrDist            matlab.ui.control.UIAxes
        ROITab                   matlab.ui.container.Tab
        GridLayout9              matlab.ui.container.GridLayout
        CurrentROIPanel          matlab.ui.container.Panel
        ROIlabelCheckBox         matlab.ui.control.CheckBox
        ROInotesEditField        matlab.ui.control.EditField
        notesEditFieldLabel      matlab.ui.control.Label
        ROInameEditField         matlab.ui.control.EditField
        nameEditFieldLabel       matlab.ui.control.Label
        ROIdeleteButton          matlab.ui.control.Button
        ROIModifyButton          matlab.ui.control.Button
        ROIPenColorButton1       matlab.ui.control.Button
        ROIPenColorButton2       matlab.ui.control.Button
        UITableROI               matlab.ui.control.Table
        ButtonPanel              matlab.ui.container.Panel
        GridLayout5              matlab.ui.container.GridLayout
        Button_7                 matlab.ui.control.Button
        Button_6                 matlab.ui.control.Button
        Button_5                 matlab.ui.control.Button
        Button_4                 matlab.ui.control.Button
        Button_3                 matlab.ui.control.Button
        Button_2                 matlab.ui.control.Button
        Button                   matlab.ui.control.Button
        ToolOnOffButton          matlab.ui.control.StateButton
        BackColorButton          matlab.ui.control.Button
        FrontColorButton         matlab.ui.control.Button
        SaveROIButton            matlab.ui.control.Button
        LoadROIButton            matlab.ui.control.Button
        AddROIButton             matlab.ui.control.Button
        ReadyLabel               matlab.ui.control.Label
        CompoundlistPanel        matlab.ui.container.Panel
        GridLayout2              matlab.ui.container.GridLayout
        EditField_2              matlab.ui.control.NumericEditField
        EditField_2Label         matlab.ui.control.Label
        SaveButton               matlab.ui.control.Button
        LoadButton               matlab.ui.control.Button
        UITable                  matlab.ui.control.Table
        ColorScalePanel          matlab.ui.container.Panel
        GridLayout3              matlab.ui.container.GridLayout
        ButtonGroup              matlab.ui.container.ButtonGroup
        logCheckBox              matlab.ui.control.CheckBox
        FixedButton              matlab.ui.control.RadioButton
        ManualButton             matlab.ui.control.RadioButton
        AutoButton               matlab.ui.control.RadioButton
        minEditField             matlab.ui.control.NumericEditField
        minEditFieldLabel        matlab.ui.control.Label
        maxEditField             matlab.ui.control.NumericEditField
        maxEditFieldLabel        matlab.ui.control.Label
        Slider_lb                matlab.ui.control.Slider
        Slider_ub                matlab.ui.control.Slider
        MSpeaknavigatorPanel     matlab.ui.container.Panel
        GridLayout4              matlab.ui.container.GridLayout
        maxMEditField            matlab.ui.control.EditField
        mzEditField              matlab.ui.control.NumericEditField
        mzEditFieldLabel         matlab.ui.control.Label
        mzoffsetppmSpinner       matlab.ui.control.Spinner
        mzoffsetppmSpinnerLabel  matlab.ui.control.Label
        mzwindowppmSpinner       matlab.ui.control.Spinner
        mzwindowppmSpinnerLabel  matlab.ui.control.Label
        ntmassEditField          matlab.ui.control.NumericEditField
        ntmassEditFieldLabel     matlab.ui.control.Label
        isoMDropDown             matlab.ui.control.DropDown
        DropDown_4Label          matlab.ui.control.Label
        isoTypeDropDown          matlab.ui.control.DropDown
        IonFormatDropDown        matlab.ui.control.DropDown
        Label_3                  matlab.ui.control.Label
        chargeDropDown           matlab.ui.control.DropDown
        Label_2                  matlab.ui.control.Label
        FormulaEditField         matlab.ui.control.EditField
        FormulaEditFieldLabel    matlab.ui.control.Label
        NameEditField            matlab.ui.control.EditField
        NameEditFieldLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        msi % msi
        pks
        pk
        h
        clim
        state % 1. msi loaded  2. iso on
        
    end
    
    properties (Access = public)
       roiInput % Description
    end
    
    methods (Access = private)        
        function pks = loadpks(app,fname)
            T=readtable(fname);
            set(app.UITable,'data',T);            
            S=table2struct(T);
            %pks.data=dt;
            pks.sdata=S; %structure data
            pks.pkid=1;
            pks.ordering=1:size(T,1);
            pks.corref=zeros(length(S),length(S));
            pks.filename=fname;
        end
        
        function msi = msi_ini(app,msi)
            if max([msi.data.x])>100
                msi.padding=[10,10,10,10]; % set padding
            elseif max([msi.data.x])>20
                msi.padding=[2,2,2,2]; 
            else      
                msi.padding=[1,1,1,1];
            end  
            msi=msi_get_metadata(msi); %initialize, get metadata,ref and alphadata
            msi.pk=app.pk;
            msi.cmap=parula; 
            msi=msi_get_idata(msi); % get 1d intensity data from mypk
            msi=msi_update_imgdata(msi); % update to get 2d imgdata
            msi.wdata=ones(size(msi.imgdata));%initialize weight=1 for each pixel
            msi.isoidata=[];
            msi.currentID=1;  
            msi=msi_get_ms(msi); 
            msi=msi_get_TIC(msi);            
       
            colorscale=[0,1];  %adjust color brightness
            msi.CLim=(max(msi.idata)+1e-9)*colorscale;
            msi.bgColor=app.BackColorButton.BackgroundColor;
        end
        
        function setpkUI(app,pk)
            app.NameEditField.Value=pk.name;
            app.FormulaEditField.Value=pk.formula;
            app.ntmassEditField.Value=pk.mass;
            app.mzwindowppmSpinner.Value=pk.ppm;
            app.mzoffsetppmSpinner.Value=pk.offset;
            app.chargeDropDown.ItemsData=-2:2;
            app.chargeDropDown.Value=pk.z;
            app.IonFormatDropDown.Items=pk.addList_;
            app.IonFormatDropDown.ItemsData=1:length(pk.addList_);            
            app.IonFormatDropDown.Value=pk.addType;
            app.isoTypeDropDown.Items=pk.isoName;
            app.isoTypeDropDown.ItemsData=1:length(pk.isoName);            
            app.isoTypeDropDown.Value=pk.isoType;
            app.isoMDropDown.Items=pk.MList_;
            app.isoMDropDown.ItemsData=1:length(pk.MList_);
            app.isoMDropDown.Value=pk.M_;
            app.maxMEditField.Value=num2str(pk.maxM_);
            app.mzEditField.Value=pk.mz_;
        end
        
        function getpkSettingUI(app)
            app.pk.ppm=app.mzwindowppmSpinner.Value;
            app.pk.offset=app.mzoffsetppmSpinner.Value;
            app.pk.z=app.chargeDropDown.Value; %z pass over
            app.pk.addType=app.IonFormatDropDown.Value;%addType pass over
            app.pk.isoType=app.isoTypeDropDown.Value; %isoType pass over            
        end
        
        function clim = getCLim(app) % get clim based on color scale panel and idata
            % clim = [lb, ub] in real values
            if isempty(app.msi)
                clim=[0,1];
                return
            end
            idata=app.msi.idata;
            mx=max(idata)+1e-9;
            if    app.AutoButton.Value                
                  lb=0;
                  ub=max(prctile(idata,99),min(idata(idata>0)));
                  if isempty(ub)
                    ub=lb+1e-9;
                  end
                  app.Slider_lb.Value=lb/mx;
                  app.Slider_ub.Value=ub/mx;
                  app.Slider_lb.Enable=false;
                  app.Slider_ub.Enable=false;
                  app.minEditField.Editable=false;
                  app.maxEditField.Editable=false;
                  app.minEditField.Value=lb;
                  app.maxEditField.Value=ub;
           elseif app.ManualButton.Value                  
                  app.Slider_lb.Enable=true;
                  app.Slider_ub.Enable=true;
                  lb=mx*app.Slider_lb.Value;
                  ub=mx*app.Slider_ub.Value;
                  app.minEditField.Editable=false;
                  app.maxEditField.Editable=false;
                  app.minEditField.Value=lb;
                  app.maxEditField.Value=ub;
           elseif app.FixedButton.Value
                  app.Slider_lb.Enable=false;
                  app.Slider_ub.Enable=false;
                  app.minEditField.Editable=true;
                  app.maxEditField.Editable=true;
                  lb=app.minEditField.Value;
                  ub=app.maxEditField.Value;                
           end
            if lb>ub
                ub=lb+1e9;
            end
            clim=[lb,ub];
        end
        
        function mypostcallback(app, event)
           app.msi.scaleobj.update;           
        end
        
        function axes1_ButtonDownFcn(app,event)
            coordinates = app.UIAxesMain.CurrentPoint;
            x=round(coordinates(1,1)/app.msi.res*1000);
            y=round(coordinates(1,2)/app.msi.res*1000);
            app.CursorXLabel.Text=['X=',num2str(x)];
            app.CursorYLabel.Text=['Y=',num2str(y)];
            app.msi.currentID=find(app.msi.metadata(:,1)==x & app.msi.metadata(:,2)==y);
            app.update_ID;
        end
        
        function update_ID(app)
            id=app.msi.currentID;
            if isempty(id)
              app.CursorIDSpinner.Value=0;
              app.CursorSigLabel.Text='Sig=N/A';
              sig=nan;
            else
                x=app.msi.metadata(id,1);
                y=app.msi.metadata(id,2);
                app.CursorXLabel.Text=['X=',num2str(x)];
                app.CursorYLabel.Text=['Y=',num2str(y)];
                sig=app.msi.idata(id);
                app.msi.currentID=id;
                app.msi.cursorobj.update(app.msi);
                app.CursorIDSpinner.Value=id;                        
                if isempty(sig)
                    sig=nan;
                end
            app.msi=msi_get_ms(app.msi);
            app.msi.msobj.XData=app.msi.ms.XData; %update msobj
            app.msi.msobj.YData=app.msi.ms.YData;
            app.msi.msobj2.XData=app.msi.ms.XData; %update msobj2
            app.msi.msobj2.YData=app.msi.ms.YData;
            xlim(app.UIAxesMS2,[app.pk.mz_-2,app.pk.mz_+2]);
            ind1=app.msi.roigrp(app.ListBox1.Value).BW.*app.msi.imgScanIDdata;
            ids=ind1(:);ids=ids(ids>0);
            app.msi.sigDistobj.XData=ids;
            app.msi.sigDistobj.YData=app.msi.idata(ids); %update sigDistobj
            app.UIAxesSigDist.XLim=[0,length(app.msi.idata)];
            app.msi.sigHistobj.Data=app.msi.idata(ids);  %update sigHistobj
                        ind2=app.msi.roigrp(app.ListBox2.Value).BW.*app.msi.imgScanIDdata;
            ids=ind2(:);ids=ids(ids>0);
            errdata=app.msi.errdata(ids);
            app.msi.errDistobj.Data=errdata(errdata>-99); %update errdata
            xlim(app.UIAxesErrDist,[-app.pk.ppm,app.pk.ppm]); 
            
            end
            app.CursorSigLabel.Text=['Sig=',num2str(sig,'%.3g')];            
        end
        
        function update_pk(app)
            if app.state(1)
               app.msi.pk=app.pk; %update pk
               app.msi=msi_get_idata(app.msi); 
               app.msi=msi_update_imgdata(app.msi);
               app.msi.imobj.CData=app.msi.imgdata;
               app.UIAxesMain.CLim=app.getCLim();
               app.update_ID;
            end            
        end
        
        function update_roigrp(app)
           update_roitable(app.UITableROI,app.msi.roigrp);
           app.ListBox1.Items={app.msi.roigrp.tag};
           app.ListBox1.ItemsData=1:length(app.msi.roigrp);
           app.ListBox1.Value=app.ListBox1.ItemsData(1);
           app.ListBox2.Items={app.msi.roigrp.tag};
           app.ListBox2.ItemsData=1:length(app.msi.roigrp);
           app.ListBox2.Value=app.ListBox2.ItemsData(1);
            id=app.msi.currentROI;
            app.ROIPenColorButton1.BackgroundColor=app.msi.roigrp(id).c1;
            app.ROIPenColorButton2.BackgroundColor=app.msi.roigrp(id).c2;
            app.ROInameEditField.Value=app.msi.roigrp(id).tag;
            app.ROInotesEditField.Value=app.msi.roigrp(id).note;  
            app.ROIlabelCheckBox.Value=~isempty(app.msi.roigrp(id).plt.Label);
           for i=1:length(app.msi.roigrp)
               myroi=app.msi.roigrp(i);
               [myroi.sig,myroi.coverage]=myroi.get_signal(app.msi.imgdata);
               app.msi.roigrp(i)=myroi;
           end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           % Create PushButtons
            app.pks=app.loadpks('list00.xlsx');
            app.pk=Mzpk(app.pks.sdata(app.pks.pkid)); %define an arbitrary peak from the peak list.
            app.setpkUI(app.pk);
            app.h=zoom(app.UIAxesMain);
            app.h.ActionPostCallback = createCallbackFcn(app, @mypostcallback, true);           
            %app.h.pan.ActionPostCallback = @mypostcallback;
        end

        % Callback function: OpenMenu, PushToolOpen
        function OpenMenuSelected(app, event)
             [fileName,filePath] = uigetfile('*.mat');
             fname=fullfile(filePath,fileName);             
             if isequal(fileName,0)
               disp('User selected Cancel');
               return
             end
             O = load (fname);
              app.msi=O.msi;
                 app.msi = app.msi_ini(app.msi);
                 app.state(1)=1;  %msi loaded              
              %   app.state(1)=0;             
                 app.msi.imobj=msi_create_imobj(app.UIAxesMain,app.msi);
                 app.msi.imobj.ButtonDownFcn = createCallbackFcn(app, @axes1_ButtonDownFcn, true);                 
                 app.msi.cursorobj=Pcursor(app.UIAxesMain,app.msi);
                 app.CursorIDSpinner.Limits=[0,size(app.msi.metadata,1)];                 
                 app.UIAxesMain.CLim=app.getCLim();                 
                 app.msi.scaleobj=Pscale(app.UIAxesMain);               
                 app.msi.scaleobj.update;
                 app.msi.msobj=stem(app.UIAxesMS,app.msi.ms.XData,app.msi.ms.YData,'.');
                 app.msi.msobj2=stem(app.UIAxesMS2,app.msi.ms.XData,app.msi.ms.YData,'.'); 
                 app.msi.sigDistobj=plot(app.UIAxesSigDist,1:length(app.msi.idata),app.msi.idata,'.');             
                 app.msi.sigHistobj=histogram(app.UIAxesSigHist,app.msi.idata,'Orientation','horizontal');
                 app.msi.errDistobj=histogram(app.UIAxesErrDist,app.msi.errdata(app.msi.errdata>-99));
                 xlim(app.UIAxesErrDist,[-app.pk.ppm,app.pk.ppm]); 
                 

                 myroi=Proi(app.UIFigure,app.UIAxesMain,app.msi.ref);
                 myroi.plt=images.roi.Rectangle(app.UIAxesMain);
                 myroi.tag='ALL';
                 xx=app.UIAxesMain.XLim;
                 yy=app.UIAxesMain.YLim;
                    myroi.plt.Position=[xx(1),yy(1),xx(2),yy(2)];
                    myroi.plt.Visible='off';                 
                 myroi=myroi.update();
                 [myroi.sig,myroi.coverage]=myroi.get_signal(app.msi.imgdata);
                 app.msi.currentROI=1;
                 app.msi.roigrp(1)=myroi;
                 app.update_roigrp;
                 
                 app.update_ID;
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            id=indices(1);
            app.pks.pkid=id;  %update pkid
            app.pk=Mzpk(app.pks.sdata(id)); %pk reset    
            app.getpkSettingUI;             
            app.setpkUI(app.pk);          
            app.update_pk; 
            app.update_roigrp;
        end

        % Value changed function: chargeDropDown
        function chargeDropDownValueChanged(app, event)
            value = app.chargeDropDown.Value;            
            app.pk.z=value; 
            app.pk.addType=1; %reset addType value
            app.setpkUI(app.pk);
            app.update_pk;
        end

        % Value changed function: isoTypeDropDown
        function isoTypeDropDownValueChanged(app, event)
            value = app.isoTypeDropDown.Value;
            app.pk.isoType=value; 
            app.pk.M=0; %reset M value
            app.setpkUI(app.pk);
            app.update_pk;
        end

        % Value changed function: IonFormatDropDown
        function IonFormatDropDownValueChanged(app, event)
            value = app.IonFormatDropDown.Value;
            app.pk.addType=value;       
            app.setpkUI(app.pk);
            app.update_pk;
        end

        % Value changed function: isoMDropDown
        function isoMDropDownValueChanged(app, event)
            value = app.isoMDropDown.Value;
            app.pk.M_=value;
            app.setpkUI(app.pk);
            app.update_pk;
        end

        % Callback function: ButtonGroup, Slider_lb, Slider_ub
        function ColorScaleChanged(app, event)
            app.UIAxesMain.CLim=app.getCLim();
                        
        end

        % Button pushed function: ScaleBarColorButton
        function ScaleBarColorButtonPushed(app, event)
            c=uisetcolor(app.ScaleBarColorButton.BackgroundColor);
            app.msi.scaleobj.color=c;
            app.ScaleBarColorButton.BackgroundColor=c;
            if ~isempty(app.msi)
                app.msi.scaleobj.update;
            end
        end

        % Button pushed function: ScaleBarUpdateButton
        function ScaleBarUpdateButtonPushed(app, event)
            if ~isempty(app.msi)
                app.msi.scaleobj.update;
            end
        end

        % Value changed function: ScaleBarOnOffCheckBox
        function ScaleBarOnOffCheckBoxValueChanged(app, event)
            if isempty(app.msi)
                 return
            end
            value = app.ScaleBarOnOffCheckBox.Value;                     
            if value
                app.msi.scaleobj.visible='On'; 
            else
                app.msi.scaleobj.visible='Off';
            end
            if ~isempty(app.msi)
                app.msi.scaleobj.update;
            end
        end

        % Value changed function: CursorOnOffCheckBox
        function CursorOnOffCheckBoxValueChanged(app, event)
            value = app.CursorOnOffCheckBox.Value;
            if value
                app.msi.cursorobj.Visible='on';
            else
                app.msi.cursorobj.Visible='off';
            end
            app.msi.cursorobj.update(app.msi);
        end

        % Button pushed function: CursorColorButton
        function CursorColorButtonPushed(app, event)
            c=uisetcolor(app.CursorColorButton.BackgroundColor);
            app.msi.cursorobj.Color=c;
            app.CursorColorButton.BackgroundColor=c;
            if ~isempty(app.msi)
                app.msi.scaleobj.update;
            end
            app.msi.cursorobj.update(app.msi);
        end

        % Value changed function: CursorIDSpinner, ListBox1, ListBox2
        function CursorIDSpinnerValueChanged(app, event)
            app.msi.currentID = app.CursorIDSpinner.Value;
            app.update_ID;         
        end

        % Value changed function: maxEditField, minEditField
        function maxEditFieldValueChanged(app, event)
            value = app.maxEditField.Value;
            app.update_pk;
        end

        % Value changed function: logCheckBox
        function logCheckBoxValueChanged(app, event)
            value = app.logCheckBox.Value;
            if value
                app.UIAxesMain.ColorScale='log';
            else
                app.UIAxesMain.ColorScale='linear';
            end
        end

        % Clicked callback: PushTool2
        function PushTool2Clicked(app, event)

        end

        % Clicked callback: PushTool3
        function PushTool3Clicked(app, event)
          
        end

        % Selection changed function: viewButtonGroup
        function viewButtonGroupSelectionChanged(app, event)
            selectedButton = app.viewButtonGroup.SelectedObject;
            for i=1:4
                app.viewButtonGroup.Buttons(i).BackgroundColor=[.94,.94,.94];
            end
            selectedButton.BackgroundColor='c';
            switch selectedButton.Tag
                case '1'
                    app.GridLayout.ColumnWidth={220,100,'1x',64};
                    app.GridLayout.RowHeight={36,120,120,'1x','1x',30};
                    selectedButton.BackgroundColor='c';
                    %app.GridLayoutImage.ColumnWidth(1)= num2cell(96);
                case '2'
                    app.GridLayout.ColumnWidth={200,100,'1x',64};
                    app.GridLayout.RowHeight={36,120,120,'1x',0,30};
                    %app.GridLayoutImage.ColumnWidth(1)= num2cell(96);
                case '3'
                    app.GridLayout.ColumnWidth={0,100,'1x',64};
                    app.GridLayout.RowHeight={36,120,120,'1x','1x',30};
                   
                case '4'
                    app.GridLayout.ColumnWidth={0,0,'1x',64};
                    app.GridLayout.RowHeight={36,120,120,'1x',0,30};
            end
        end

        % Value changed function: Button_scale
        function Button_scaleValueChanged(app, event)
            value = app.Button_scale.Value;
            app.ScalebarPanel.Visible=value;
        end

        % Value changed function: Button_cursor
        function Button_cursorValueChanged(app, event)
            value = app.Button_cursor.Value;
            app.CursorPanel.Visible=value;
        end

        % Button pushed function: AddROIButton
        function AddROIButtonPushed(app, event)
           ROIDialog(app,['r',num2str(length(app.msi.roigrp))]);
           if ~isempty(app.roiInput)
               myroi=Proi(app.UIFigure,app.UIAxesMain,app.msi.ref,app.roiInput.pen,app.roiInput.c1,app.roiInput.c2);
               myroi.tag=app.roiInput.tag;
               myroi.note=app.roiInput.note;
           end
             [myroi.sig,myroi.coverage]=myroi.get_signal(app.msi.imgdata);
             app.msi.roigrp=[app.msi.roigrp,myroi];
             app.msi.currentROI=length(app.msi.roigrp);
             app.update_roigrp;
             app.funTabGroup.SelectedTab=app.ROITab;
             
        end

        % Cell selection callback: UITableROI
        function UITableROICellSelection(app, event)
            
            indices = event.Indices;
            id=indices(1);
            app.msi.currentROI=id;
            app.update_roigrp;
        end

        % Cell edit callback: UITableROI
        function UITableROICellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            id=indices(1);
            app.msi.roigrp(id).plt.Visible=newData;
        end

        % Button pushed function: ROIModifyButton
        function ROIModifyButtonPushed(app, event)
            myroi=app.msi.roigrp(app.msi.currentROI);
            myroi.plt.Visible='on';
            myroi=myroi.move;
            [myroi.sig,myroi.coverage]=myroi.get_signal(app.msi.imgdata);
            app.msi.roigrp(app.msi.currentROI)=myroi;
            app.update_roigrp
        end

        % Button pushed function: ROIdeleteButton
        function ROIdeleteButtonPushed(app, event)
            if app.msi.currentROI==1
                msgbox('Cannot delete the full ROI');
            else
            app.msi.roigrp(app.msi.currentROI).delete;
            app.msi.roigrp(app.msi.currentROI)=[];
            app.update_roigrp
            end
        end

        % Value changed function: ROIlabelCheckBox, ROInameEditField, 
        % ROInotesEditField
        function CurrentROIChanged(app, event)
            app.msi.roigrp(app.msi.currentROI).tag=app.ROInameEditField.Value;
            app.msi.roigrp(app.msi.currentROI).note=app.ROInotesEditField.Value;
            if app.ROIlabelCheckBox.Value
             app.msi.roigrp(app.msi.currentROI).plt.Label=app.msi.roigrp(app.msi.currentROI).tag;
            else
             app.msi.roigrp(app.msi.currentROI).plt.Label='';
            end
            app.update_roigrp
        end

        % Button pushed function: ROIPenColorButton1
        function ROIPenColorButton1Pushed(app, event)
           c=uisetcolor(app.ROIPenColorButton1.BackgroundColor);
           app.ROIPenColorButton1.BackgroundColor=c; 
           app.msi.roigrp(app.msi.currentROI).c1=c;
           app.msi.roigrp(app.msi.currentROI)=app.msi.roigrp(app.msi.currentROI).update;
        end

        % Button pushed function: ROIPenColorButton2
        function ROIPenColorButton2Pushed(app, event)
           c=uisetcolor(app.ROIPenColorButton2.BackgroundColor);
           app.ROIPenColorButton2.BackgroundColor=c; 
           app.msi.roigrp(app.msi.currentROI).c2=c;
           app.msi.roigrp(app.msi.currentROI)=app.msi.roigrp(app.msi.currentROI).update; 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushToolOpen = uipushtool(app.Toolbar);
            app.PushToolOpen.ClickedCallback = createCallbackFcn(app, @OpenMenuSelected, true);
            app.PushToolOpen.Icon = 'load_32x32.png';
            app.PushTool2 = uipushtool(app.Toolbar);
            app.PushTool2.ClickedCallback = createCallbackFcn(app, @PushTool2Clicked, true);
            app.PushTool2.Separator = 'on';
            app.PushTool3 = uipushtool(app.Toolbar);
            app.PushTool3.ClickedCallback = createCallbackFcn(app, @PushTool3Clicked, true);
            app.PushTool4 = uipushtool(app.Toolbar);
            app.PushTool5 = uipushtool(app.Toolbar);
            app.PushTool6 = uipushtool(app.Toolbar);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.MSpeaknavigatorPanel = uipanel(app.GridLayout);
            app.MSpeaknavigatorPanel.Title = 'MS peak navigator';
            app.GridLayout4 = uigridlayout(app.MSpeaknavigatorPanel);
            app.NameEditFieldLabel = uilabel(app.GridLayout4);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.GridLayout4, 'text');
            app.NameEditField.Value = 'Glutamine';
            app.FormulaEditFieldLabel = uilabel(app.GridLayout4);
            app.FormulaEditFieldLabel.Text = 'Formula';
            app.FormulaEditField = uieditfield(app.GridLayout4, 'text');
            app.FormulaEditField.Value = 'C5H10N2O3';
            app.Label_2 = uilabel(app.GridLayout4);
            app.Label_2.Text = '';
            app.chargeDropDown = uidropdown(app.GridLayout4);
            app.chargeDropDown.Items = {'neg(-2)', 'neg(-1)', 'neutral(0)', 'pos(+1)', 'pos(+2)', ''};
            app.chargeDropDown.ValueChangedFcn = createCallbackFcn(app, @chargeDropDownValueChanged, true);
            app.chargeDropDown.Tag = 'chargeDropDown';
            app.chargeDropDown.Value = 'neg(-1)';
            app.Label_3 = uilabel(app.GridLayout4);
            app.Label_3.Text = '';
            app.IonFormatDropDown = uidropdown(app.GridLayout4);
            app.IonFormatDropDown.Items = {'M-H', 'M+Na-2H', ''};
            app.IonFormatDropDown.ValueChangedFcn = createCallbackFcn(app, @IonFormatDropDownValueChanged, true);
            app.IonFormatDropDown.Tag = 'IonFormatDropDown';
            app.IonFormatDropDown.Value = 'M-H';
            app.isoTypeDropDown = uidropdown(app.GridLayout4);
            app.isoTypeDropDown.Items = {'13C', '15N', '2D', '13C15N'};
            app.isoTypeDropDown.ItemsData = {'1', '2', '3', '4'};
            app.isoTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @isoTypeDropDownValueChanged, true);
            app.isoTypeDropDown.Tag = 'isoTypeDropDown';
            app.isoTypeDropDown.Value = '1';
            app.DropDown_4Label = uilabel(app.GridLayout4);
            app.DropDown_4Label.Text = '';
            app.isoMDropDown = uidropdown(app.GridLayout4);
            app.isoMDropDown.Items = {'0', '1'};
            app.isoMDropDown.ValueChangedFcn = createCallbackFcn(app, @isoMDropDownValueChanged, true);
            app.isoMDropDown.Tag = 'isoMDropDown';
            app.isoMDropDown.Value = '0';
            app.ntmassEditFieldLabel = uilabel(app.GridLayout4);
            app.ntmassEditFieldLabel.Text = 'nt mass';
            app.ntmassEditField = uieditfield(app.GridLayout4, 'numeric');
            app.ntmassEditField.Limits = [0 Inf];
            app.ntmassEditField.ValueDisplayFormat = '%11.4f';
            app.ntmassEditField.Tag = 'ntmassEditField';
            app.ntmassEditField.Value = 146.0691;
            app.mzwindowppmSpinnerLabel = uilabel(app.GridLayout4);
            app.mzwindowppmSpinnerLabel.Text = 'mz window (ppm)';
            app.mzwindowppmSpinner = uispinner(app.GridLayout4);
            app.mzwindowppmSpinner.Value = 5;
            app.mzoffsetppmSpinnerLabel = uilabel(app.GridLayout4);
            app.mzoffsetppmSpinnerLabel.Text = 'mz offset (ppm)';
            app.mzoffsetppmSpinner = uispinner(app.GridLayout4);
            app.mzEditFieldLabel = uilabel(app.GridLayout4);
            app.mzEditFieldLabel.Text = 'm/z';
            app.mzEditField = uieditfield(app.GridLayout4, 'numeric');
            app.mzEditField.ValueDisplayFormat = '%11.4f';
            app.mzEditField.Editable = 'off';
            app.maxMEditField = uieditfield(app.GridLayout4, 'text');
            app.maxMEditField.Value = '0';
            app.ColorScalePanel = uipanel(app.GridLayout);
            app.ColorScalePanel.Title = 'ColorScale';
            app.GridLayout3 = uigridlayout(app.ColorScalePanel);
            app.Slider_ub = uislider(app.GridLayout3);
            app.Slider_ub.Limits = [0 1];
            app.Slider_ub.MajorTicks = [];
            app.Slider_ub.ValueChangedFcn = createCallbackFcn(app, @ColorScaleChanged, true);
            app.Slider_ub.MinorTicks = [];
            app.Slider_ub.Value = 1;
            app.Slider_lb = uislider(app.GridLayout3);
            app.Slider_lb.Limits = [0 1];
            app.Slider_lb.MajorTicks = [];
            app.Slider_lb.ValueChangedFcn = createCallbackFcn(app, @ColorScaleChanged, true);
            app.Slider_lb.MinorTicks = [];
            app.maxEditFieldLabel = uilabel(app.GridLayout3);
            app.maxEditFieldLabel.Text = 'max';
            app.maxEditField = uieditfield(app.GridLayout3, 'numeric');
            app.maxEditField.ValueDisplayFormat = '%11.2g';
            app.maxEditField.ValueChangedFcn = createCallbackFcn(app, @maxEditFieldValueChanged, true);
            app.minEditFieldLabel = uilabel(app.GridLayout3);
            app.minEditFieldLabel.Text = 'min';
            app.minEditField = uieditfield(app.GridLayout3, 'numeric');
            app.minEditField.ValueDisplayFormat = '%11.2g';
            app.minEditField.ValueChangedFcn = createCallbackFcn(app, @maxEditFieldValueChanged, true);
            app.ButtonGroup = uibuttongroup(app.GridLayout3);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ColorScaleChanged, true);
            app.AutoButton = uiradiobutton(app.ButtonGroup);
            app.AutoButton.Text = {'Auto'; ''};
            app.AutoButton.Value = true;
            app.ManualButton = uiradiobutton(app.ButtonGroup);
            app.ManualButton.Text = 'Manual';
            app.FixedButton = uiradiobutton(app.ButtonGroup);
            app.FixedButton.Text = 'Fixed';
            app.logCheckBox = uicheckbox(app.ButtonGroup);
            app.logCheckBox.ValueChangedFcn = createCallbackFcn(app, @logCheckBoxValueChanged, true);
            app.logCheckBox.Text = 'log';
            app.CompoundlistPanel = uipanel(app.GridLayout);
            app.CompoundlistPanel.Title = 'Compound list';
            app.GridLayout2 = uigridlayout(app.CompoundlistPanel);
            app.UITable = uitable(app.GridLayout2);
            app.UITable.ColumnName = {'Name'; 'Formula'; 'm/z'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.LoadButton = uibutton(app.GridLayout2, 'push');
            app.LoadButton.Text = 'Load';
            app.SaveButton = uibutton(app.GridLayout2, 'push');
            app.SaveButton.Text = 'Save';
            app.EditField_2Label = uilabel(app.GridLayout2);
            app.EditField_2Label.Text = 'Edit Field';
            app.EditField_2 = uieditfield(app.GridLayout2, 'numeric');
            app.ReadyLabel = uilabel(app.GridLayout);
            app.ReadyLabel.Text = 'Ready';
            app.ButtonPanel = uipanel(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.ButtonPanel);
            app.AddROIButton = uibutton(app.GridLayout5, 'push');
            app.AddROIButton.ButtonPushedFcn = createCallbackFcn(app, @AddROIButtonPushed, true);
            app.AddROIButton.Icon = 'ROI_32x32.png';
            app.AddROIButton.Text = '';
            app.LoadROIButton = uibutton(app.GridLayout5, 'push');
            app.LoadROIButton.Text = 'Load';
            app.SaveROIButton = uibutton(app.GridLayout5, 'push');
            app.SaveROIButton.Text = 'Save';
            app.FrontColorButton = uibutton(app.GridLayout5, 'push');
            app.FrontColorButton.Icon = 'rainbow_32x32.png';
            app.FrontColorButton.Text = '';
            app.BackColorButton = uibutton(app.GridLayout5, 'push');
            app.BackColorButton.Text = '';
            app.ToolOnOffButton = uibutton(app.GridLayout5, 'state');
            app.ToolOnOffButton.Text = 'Tool';
            app.ToolOnOffButton.Value = true;
            app.Button = uibutton(app.GridLayout5, 'push');
            app.Button_2 = uibutton(app.GridLayout5, 'push');
            app.Button_2.Icon = 'targeted_32x32.png';
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.GridLayout5, 'push');
            app.Button_3.Icon = 'untargeted_32x32.png';
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.GridLayout5, 'push');
            app.Button_4.Text = '>';
            app.Button_5 = uibutton(app.GridLayout5, 'push');
            app.Button_5.Text = '>>';
            app.Button_6 = uibutton(app.GridLayout5, 'push');
            app.Button_6.Icon = 'select_32x32.png';
            app.Button_6.Text = '';
            app.Button_7 = uibutton(app.GridLayout5, 'push');
            app.Button_7.Icon = 'Save_32x32.png';
            app.Button_7.Text = '';
            app.funTabGroup = uitabgroup(app.GridLayout);
            app.MSTab = uitab(app.funTabGroup);
            app.MSTab.Title = 'MS';
            app.GridLayout6 = uigridlayout(app.MSTab);
            app.UIAxesMS2 = uiaxes(app.GridLayout6);
            title(app.UIAxesMS2, 'MS')
            xlabel(app.UIAxesMS2, 'm/z')
            ylabel(app.UIAxesMS2, 'sig')
            zlabel(app.UIAxesMS2, 'Z')
            app.UIAxesMS = uiaxes(app.GridLayout6);
            title(app.UIAxesMS, 'MS')
            xlabel(app.UIAxesMS, 'm/z')
            ylabel(app.UIAxesMS, 'sig')
            zlabel(app.UIAxesMS, 'Z')
            app.DintensityTab = uitab(app.funTabGroup);
            app.DintensityTab.Title = '1D intensity';
            app.GridLayout10 = uigridlayout(app.DintensityTab);
            app.UIAxesSigDist = uiaxes(app.GridLayout10);
            xlabel(app.UIAxesSigDist, 'Scan ID')
            ylabel(app.UIAxesSigDist, 'Sig')
            zlabel(app.UIAxesSigDist, 'Z')
            app.UIAxesSigHist = uiaxes(app.GridLayout10);
            xlabel(app.UIAxesSigHist, 'Scan ID')
            ylabel(app.UIAxesSigHist, 'Sig')
            zlabel(app.UIAxesSigHist, 'Z')
            app.ListBox1 = uilistbox(app.GridLayout10);
            app.ListBox1.Items = {};
            app.ListBox1.ValueChangedFcn = createCallbackFcn(app, @CursorIDSpinnerValueChanged, true);
            app.ListBox1.Value = {};
            app.ErrdistTab = uitab(app.funTabGroup);
            app.ErrdistTab.Title = 'Err dist';
            app.GridLayout7 = uigridlayout(app.ErrdistTab);
            app.UIAxesErrDist = uiaxes(app.GridLayout7);
            xlabel(app.UIAxesErrDist, 'm/z error (ppm)')
            ylabel(app.UIAxesErrDist, 'Count')
            zlabel(app.UIAxesErrDist, 'Z')
            app.ListBox2 = uilistbox(app.GridLayout7);
            app.ListBox2.Items = {};
            app.ListBox2.ValueChangedFcn = createCallbackFcn(app, @CursorIDSpinnerValueChanged, true);
            app.ListBox2.Value = {};
            app.ROITab = uitab(app.funTabGroup);
            app.ROITab.Title = 'ROI';
            app.GridLayout9 = uigridlayout(app.ROITab);
            app.UITableROI = uitable(app.GridLayout9);
            app.UITableROI.ColumnName = {'on/off'; 'ROI name'; 'PenType'; 'area'; 'SIgnal'; '#Sig/0/NA'; 'notes'};
            app.UITableROI.RowName = {};
            app.UITableROI.ColumnEditable = [true false false false false false false];
            app.UITableROI.CellEditCallback = createCallbackFcn(app, @UITableROICellEdit, true);
            app.UITableROI.CellSelectionCallback = createCallbackFcn(app, @UITableROICellSelection, true);
            app.CurrentROIPanel = uipanel(app.GridLayout9);
            app.CurrentROIPanel.Title = 'Current ROI';
            app.ROIPenColorButton2 = uibutton(app.CurrentROIPanel, 'push');
            app.ROIPenColorButton2.ButtonPushedFcn = createCallbackFcn(app, @ROIPenColorButton2Pushed, true);
            app.ROIPenColorButton2.Text = '';
            app.ROIPenColorButton1 = uibutton(app.CurrentROIPanel, 'push');
            app.ROIPenColorButton1.ButtonPushedFcn = createCallbackFcn(app, @ROIPenColorButton1Pushed, true);
            app.ROIPenColorButton1.Text = '';
            app.ROIModifyButton = uibutton(app.CurrentROIPanel, 'push');
            app.ROIModifyButton.ButtonPushedFcn = createCallbackFcn(app, @ROIModifyButtonPushed, true);
            app.ROIModifyButton.Text = 'Modify';
            app.ROIdeleteButton = uibutton(app.CurrentROIPanel, 'push');
            app.ROIdeleteButton.ButtonPushedFcn = createCallbackFcn(app, @ROIdeleteButtonPushed, true);
            app.ROIdeleteButton.Text = 'Delete';
            app.nameEditFieldLabel = uilabel(app.CurrentROIPanel);
            app.nameEditFieldLabel.Text = 'name';
            app.ROInameEditField = uieditfield(app.CurrentROIPanel, 'text');
            app.ROInameEditField.ValueChangedFcn = createCallbackFcn(app, @CurrentROIChanged, true);
            app.notesEditFieldLabel = uilabel(app.CurrentROIPanel);
            app.notesEditFieldLabel.Text = 'notes';
            app.ROInotesEditField = uieditfield(app.CurrentROIPanel, 'text');
            app.ROInotesEditField.ValueChangedFcn = createCallbackFcn(app, @CurrentROIChanged, true);
            app.ROIlabelCheckBox = uicheckbox(app.CurrentROIPanel);
            app.ROIlabelCheckBox.ValueChangedFcn = createCallbackFcn(app, @CurrentROIChanged, true);
            app.ROIlabelCheckBox.Text = 'label';
            app.imgTabGroup = uitabgroup(app.GridLayout);
            app.ImageTab = uitab(app.imgTabGroup);
            app.ImageTab.Title = 'Image';
            app.GridLayoutImage = uigridlayout(app.ImageTab);
            app.UIAxesMain = uiaxes(app.GridLayoutImage);
            app.UIAxesMain.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.ScalebarPanel = uipanel(app.GridLayoutImage);
            app.ScalebarPanel.Title = 'Scalebar';
            app.ScaleBarColorButton = uibutton(app.ScalebarPanel, 'push');
            app.ScaleBarColorButton.ButtonPushedFcn = createCallbackFcn(app, @ScaleBarColorButtonPushed, true);
            app.ScaleBarColorButton.Text = '';
            app.ScaleBarUpdateButton = uibutton(app.ScalebarPanel, 'push');
            app.ScaleBarUpdateButton.ButtonPushedFcn = createCallbackFcn(app, @ScaleBarUpdateButtonPushed, true);
            app.ScaleBarUpdateButton.Icon = 'refresh_32x32.png';
            app.ScaleBarUpdateButton.Text = '';
            app.ScaleBarOnOffCheckBox = uicheckbox(app.ScalebarPanel);
            app.ScaleBarOnOffCheckBox.ValueChangedFcn = createCallbackFcn(app, @ScaleBarOnOffCheckBoxValueChanged, true);
            app.ScaleBarOnOffCheckBox.Text = 'on/off';
            app.ScaleBarOnOffCheckBox.Value = true;
            app.CursorPanel = uipanel(app.GridLayoutImage);
            app.CursorPanel.Title = 'Cursor';
            app.CursorOnOffCheckBox = uicheckbox(app.CursorPanel);
            app.CursorOnOffCheckBox.ValueChangedFcn = createCallbackFcn(app, @CursorOnOffCheckBoxValueChanged, true);
            app.CursorOnOffCheckBox.Text = {'on/off'; ''};
            app.CursorOnOffCheckBox.Value = true;
            app.CursorColorButton = uibutton(app.CursorPanel, 'push');
            app.CursorColorButton.ButtonPushedFcn = createCallbackFcn(app, @CursorColorButtonPushed, true);
            app.CursorColorButton.Text = '';
            app.CursorXLabel = uilabel(app.CursorPanel);
            app.CursorXLabel.Text = {'X = '; ''};
            app.CursorYLabel = uilabel(app.CursorPanel);
            app.CursorYLabel.Text = {'Y = '; ''};
            app.IDSpinnerLabel = uilabel(app.CursorPanel);
            app.IDSpinnerLabel.Text = 'ID';
            app.CursorIDSpinner = uispinner(app.CursorPanel);
            app.CursorIDSpinner.Limits = [0 Inf];
            app.CursorIDSpinner.ValueDisplayFormat = '%.0f';
            app.CursorIDSpinner.ValueChangedFcn = createCallbackFcn(app, @CursorIDSpinnerValueChanged, true);
            app.CursorIDSpinner.Value = 1;
            app.CursorSigLabel = uilabel(app.CursorPanel);
            app.CursorSigLabel.Text = {'Sig = '; ''};
            app.IsoViewTab = uitab(app.imgTabGroup);
            app.IsoViewTab.Title = 'IsoView';
            app.WeightTab = uitab(app.imgTabGroup);
            app.WeightTab.Title = 'Weight';
            app.SettingsTab = uitab(app.imgTabGroup);
            app.SettingsTab.Title = 'Settings';
            app.viewButtonGroup = uibuttongroup(app.GridLayout);
            app.viewButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @viewButtonGroupSelectionChanged, true);
            app.viewButtonGroup.Title = 'view';
            app.ButtonView1 = uitogglebutton(app.viewButtonGroup);
            app.ButtonView1.Tag = '1';
            app.ButtonView1.Icon = 'view_1_32x32.png';
            app.ButtonView1.Text = '';
            app.ButtonView1.Value = true;
            app.ButtonView2 = uitogglebutton(app.viewButtonGroup);
            app.ButtonView2.Tag = '2';
            app.ButtonView2.Icon = 'view_2_32x32.png';
            app.ButtonView2.Text = '';
            app.ButtonView3 = uitogglebutton(app.viewButtonGroup);
            app.ButtonView3.Tag = '3';
            app.ButtonView3.Icon = 'view_3_32x32.png';
            app.ButtonView3.Text = '';
            app.ButtonView4 = uitogglebutton(app.viewButtonGroup);
            app.ButtonView4.Tag = '4';
            app.ButtonView4.Icon = 'view_4_32x32.png';
            app.ButtonView4.Text = '';
            app.GridLayout11 = uigridlayout(app.GridLayout);
            app.Button_scale = uibutton(app.GridLayout11, 'state');
            app.Button_scale.ValueChangedFcn = createCallbackFcn(app, @Button_scaleValueChanged, true);
            app.Button_scale.Icon = 'scalebar_32x32.png';
            app.Button_scale.Text = '';
            app.Button_scale.Value = true;
            app.Button_cursor = uibutton(app.GridLayout11, 'state');
            app.Button_cursor.ValueChangedFcn = createCallbackFcn(app, @Button_cursorValueChanged, true);
            app.Button_cursor.Icon = 'cursor_32x32.png';
            app.Button_cursor.Text = '';
            app.Button_cursor.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IsoScope

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end