classdef ROIDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        PenTypeDropDown        matlab.ui.control.DropDown
        PenTypeDropDownLabel   matlab.ui.control.Label
        ROIStripeColorLabel    matlab.ui.control.Label
        ROIColorLabel          matlab.ui.control.Label
        CancelButton           matlab.ui.control.Button
        OKButton               matlab.ui.control.Button
        ColorButton1           matlab.ui.control.Button
        ColorButton2           matlab.ui.control.Button
        NotesEditField         matlab.ui.control.EditField
        NotesEditFieldLabel    matlab.ui.control.Label
        ROInameEditField       matlab.ui.control.EditField
        ROInameEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        mainapp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, roiname)
            app.UIFigure.Visible = 'off';
            movegui(app.UIFigure,'center')
            app.UIFigure.Visible = 'on';
            app.mainapp=mainapp;
            app.ROInameEditField.Value=roiname;
            if ~isempty(app.mainapp.roiInput)
                app.ColorButton1.BackgroundColor=app.mainapp.roiInput.c1;
                app.ColorButton2.BackgroundColor=app.mainapp.roiInput.c2;
                app.PenTypeDropDown.Value=app.mainapp.roiInput.pen;
            end
            app.mainapp.roiInput=[];
            uiwait(app.UIFigure);
        end

        % Button pushed function: ColorButton1
        function ColorButton1Pushed(app, event)
           c=uisetcolor(app.ColorButton1.BackgroundColor);
           app.ColorButton1.BackgroundColor=c;           
          
        end

        % Button pushed function: ColorButton2
        function ColorButton2Pushed(app, event)
           c=uisetcolor(app.ColorButton2.BackgroundColor);
           app.ColorButton2.BackgroundColor=c;           
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.mainapp.roiInput.tag=app.ROInameEditField.Value;
            app.mainapp.roiInput.c1=app.ColorButton1.BackgroundColor;
            app.mainapp.roiInput.c2=app.ColorButton2.BackgroundColor;
            app.mainapp.roiInput.pen=app.PenTypeDropDown.Value;
            app.mainapp.roiInput.note=app.NotesEditField.Value;
            app.UIFigureCloseRequest(event);
        end

        % Callback function: CancelButton, UIFigure
        function UIFigureCloseRequest(app, event)
            uiresume(app.UIFigure)
            delete(app)            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ROInameEditFieldLabel = uilabel(app.UIFigure);
            app.ROInameEditFieldLabel.Text = 'ROI name';
            app.ROInameEditField = uieditfield(app.UIFigure, 'text');
            app.NotesEditFieldLabel = uilabel(app.UIFigure);
            app.NotesEditFieldLabel.Text = 'Notes';
            app.NotesEditField = uieditfield(app.UIFigure, 'text');
            app.ColorButton2 = uibutton(app.UIFigure, 'push');
            app.ColorButton2.ButtonPushedFcn = createCallbackFcn(app, @ColorButton2Pushed, true);
            app.ColorButton2.Text = '';
            app.ColorButton1 = uibutton(app.UIFigure, 'push');
            app.ColorButton1.ButtonPushedFcn = createCallbackFcn(app, @ColorButton1Pushed, true);
            app.ColorButton1.Text = '';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CancelButton.Text = 'Cancel';
            app.ROIColorLabel = uilabel(app.UIFigure);
            app.ROIColorLabel.Text = 'ROI Color';
            app.ROIStripeColorLabel = uilabel(app.UIFigure);
            app.ROIStripeColorLabel.Text = 'ROI Stripe Color';
            app.PenTypeDropDownLabel = uilabel(app.UIFigure);
            app.PenTypeDropDownLabel.Text = 'Pen Type';
            app.PenTypeDropDown = uidropdown(app.UIFigure);
            app.PenTypeDropDown.Items = {'Polygon', 'rectangle', 'freehand'};
            app.PenTypeDropDown.Value = 'Polygon';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ROIDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end